classdef FSAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadMenu                       matlab.ui.container.Menu
        FSASMenu                       matlab.ui.container.Menu
        LoadFSASForceRampsMenu         matlab.ui.container.Menu
        LoadFSASForceVolumeMenu        matlab.ui.container.Menu
        Nanoscope8Menu                 matlab.ui.container.Menu
        LoadNanoscope8ForceRampMenu    matlab.ui.container.Menu
        LoadNanoscope8ForceVolumeMenu  matlab.ui.container.Menu
        Nanoscope9Menu                 matlab.ui.container.Menu
        LoadNanoscope9ForceRampMenu    matlab.ui.container.Menu
        LoadNanoscope9ForceVolumeMenu  matlab.ui.container.Menu
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            addpath(strcat(pwd,'/ManualFigures'));
        end

        % Menu selected function: LoadFSASForceRampsMenu
        function LoadFSASForceRampsMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] = uigetfile('*.mat','Choose FSAS Force Ramps File');
            ForceRampAnalysis(FileName, FilePath, 1);
        end

        % Menu selected function: LoadFSASForceVolumeMenu
        function LoadFSASForceVolumeMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] =...
                uigetfile('*.mat','Choose FSAS Force Volume File');
            ForceVolumeAnalysis(FileName, FilePath, 1, 0);
        end

        % Menu selected function: LoadNanoscope8ForceRampMenu
        function LoadNanoscope8ForceRampMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileNames, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Ramp Files','MultiSelect','on');
            ForceRampAnalysis(FileNames,FilePath, 2, 16);
        end

        % Menu selected function: LoadNanoscope8ForceVolumeMenu
        function LoadNanoscope8ForceVolumeMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Volume File');
            ForceVolumeAnalysis(FileName, FilePath, 2, 16);
        end

        % Menu selected function: LoadNanoscope9ForceVolumeMenu
        function LoadNanoscope9ForceVolumeMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Volume File');
            ForceVolumeAnalysis(FileName, FilePath, 2, 32);
        end

        % Menu selected function: LoadNanoscope9ForceRampMenu
        function LoadNanoscope9ForceRampMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileNames, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Ramp Files','MultiSelect','on');
            ForceRampAnalysis(FileNames,FilePath, 2, 32);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FSAS';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.Text = 'Load';
            app.FSASMenu = uimenu(app.LoadMenu);
            app.FSASMenu.Text = 'FSAS';
            app.LoadFSASForceRampsMenu = uimenu(app.FSASMenu);
            app.LoadFSASForceRampsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFSASForceRampsMenuSelected, true);
            app.LoadFSASForceRampsMenu.Text = 'Force Ramp';
            app.LoadFSASForceVolumeMenu = uimenu(app.FSASMenu);
            app.LoadFSASForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFSASForceVolumeMenuSelected, true);
            app.LoadFSASForceVolumeMenu.Text = 'Force Volume';
            app.Nanoscope8Menu = uimenu(app.LoadMenu);
            app.Nanoscope8Menu.Text = 'Nanoscope 8';
            app.LoadNanoscope8ForceRampMenu = uimenu(app.Nanoscope8Menu);
            app.LoadNanoscope8ForceRampMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNanoscope8ForceRampMenuSelected, true);
            app.LoadNanoscope8ForceRampMenu.Text = 'Force Ramp';
            app.LoadNanoscope8ForceVolumeMenu = uimenu(app.Nanoscope8Menu);
            app.LoadNanoscope8ForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNanoscope8ForceVolumeMenuSelected, true);
            app.LoadNanoscope8ForceVolumeMenu.Text = 'Force Volume';
            app.Nanoscope9Menu = uimenu(app.LoadMenu);
            app.Nanoscope9Menu.Text = 'Nanoscope 9';
            app.LoadNanoscope9ForceRampMenu = uimenu(app.Nanoscope9Menu);
            app.LoadNanoscope9ForceRampMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNanoscope9ForceRampMenuSelected, true);
            app.LoadNanoscope9ForceRampMenu.Text = 'Force Ramp';
            app.LoadNanoscope9ForceVolumeMenu = uimenu(app.Nanoscope9Menu);
            app.LoadNanoscope9ForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNanoscope9ForceVolumeMenuSelected, true);
            app.LoadNanoscope9ForceVolumeMenu.Text = 'Force Volume';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'LogoFSAS.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end