classdef FitParametersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EditFieldLabel         matlab.ui.control.Label
        EditField_MinParam1    matlab.ui.control.NumericEditField
        EditField_StartParam1  matlab.ui.control.NumericEditField
        EditField_MaxParam1    matlab.ui.control.NumericEditField
        EditField_StartParam2  matlab.ui.control.NumericEditField
        EditField_MaxParam2    matlab.ui.control.NumericEditField
        EditField_6Label       matlab.ui.control.Label
        EditField_MinParam2    matlab.ui.control.NumericEditField
        EditField_StartParam3  matlab.ui.control.NumericEditField
        EditField_MaxParam3    matlab.ui.control.NumericEditField
        EditField_9Label       matlab.ui.control.Label
        EditField_MinParam3    matlab.ui.control.NumericEditField
        EditField_StartParam4  matlab.ui.control.NumericEditField
        EditField_MaxParam4    matlab.ui.control.NumericEditField
        EditField_12Label      matlab.ui.control.Label
        EditField_MinParam4    matlab.ui.control.NumericEditField
        MinimumLabel           matlab.ui.control.Label
        StartLabel             matlab.ui.control.Label
        MaximumLabel           matlab.ui.control.Label
        OKButton               matlab.ui.control.Button
        TitleLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Calling Application
        Option % Specifies the purpose of the application
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, option)
            app.CallingApp = mainapp;
            app.Option=option;
            switch option
                case "LinearFit"
                    app.EditFieldLabel.Text = "Slope";
                    app.EditField_6Label.Text = "Y0";
                    app.EditField_9Label.Visible = "Off";
                    app.EditField_12Label.Visible = "Off";
                    app.EditField_MinParam3.Visible = "Off";
                    app.EditField_StartParam3.Visible = "Off";
                    app.EditField_MaxParam3.Visible = "Off";
                    app.EditField_MinParam4.Visible = "Off";
                    app.EditField_StartParam4.Visible = "Off";
                    app.EditField_MaxParam4.Visible = "Off"; 
                    app.TitleLabel.Text = "Linear Fit Parameters";
                case "ExponentialFit"
                    app.EditFieldLabel.Text = "Exponential Amplitude (nN)";
                    app.EditField_6Label.Text = "Exponential Length (nm)";
                    app.EditField_9Label.Visible = "Off";
                    app.EditField_12Label.Visible = "Off";
                    app.EditField_MinParam3.Visible = "Off";
                    app.EditField_StartParam3.Visible = "Off";
                    app.EditField_MaxParam3.Visible = "Off";
                    app.EditField_MinParam4.Visible = "Off";
                    app.EditField_StartParam4.Visible = "Off";
                    app.EditField_MaxParam4.Visible = "Off"; 
                    app.TitleLabel.Text = "Exponential Fit Parameters";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            switch app.Option
                case "LinearFit"
                    app.CallingApp.AnalysisParameters.MinP1 = app.EditField_MinParam1.Value;
                    app.CallingApp.AnalysisParameters.StartP1 = app.EditField_StartParam1.Value;
                    app.CallingApp.AnalysisParameters.MaxP1 = app.EditField_MaxParam1.Value;
                    app.CallingApp.AnalysisParameters.MinP2 = app.EditField_MinParam2.Value;
                    app.CallingApp.AnalysisParameters.StartP2 = app.EditField_StartParam2.Value;
                    app.CallingApp.AnalysisParameters.MaxP2 = app.EditField_MaxParam2.Value;
                case "ExponentialFit"
                    app.CallingApp.AnalysisParameters.MinP1 = app.EditField_MinParam1.Value;
                    app.CallingApp.AnalysisParameters.StartP1 = app.EditField_StartParam1.Value;
                    app.CallingApp.AnalysisParameters.MaxP1 = app.EditField_MaxParam1.Value;
                    app.CallingApp.AnalysisParameters.MinP2 = app.EditField_MinParam2.Value;
                    app.CallingApp.AnalysisParameters.StartP2 = app.EditField_StartParam2.Value;
                    app.CallingApp.AnalysisParameters.MaxP2 = app.EditField_MaxParam2.Value;
            end
            uiresume(app.CallingApp.UIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField_MinParam1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MinParam1.Value = 0.001;
            app.EditField_StartParam1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_StartParam1.Value = 0.1;
            app.EditField_MaxParam1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MaxParam1.Value = 0.5;
            app.EditField_StartParam2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_StartParam2.Value = 20;
            app.EditField_MaxParam2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MaxParam2.Value = 200;
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'Edit Field';
            app.EditField_MinParam2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MinParam2.Value = 1;
            app.EditField_StartParam3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MaxParam3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = 'Edit Field';
            app.EditField_MinParam3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_StartParam4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_MaxParam4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12Label = uilabel(app.UIFigure);
            app.EditField_12Label.Text = 'Edit Field';
            app.EditField_MinParam4 = uieditfield(app.UIFigure, 'numeric');
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TitleLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitParametersApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end