classdef ForceRampAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FZViewMenu                     matlab.ui.container.Menu
        ScanDirectionMenu              matlab.ui.container.Menu
        ForwardMenu                    matlab.ui.container.Menu
        BackwardMenu                   matlab.ui.container.Menu
        BothMenu                       matlab.ui.container.Menu
        XRepresentationMenu            matlab.ui.container.Menu
        XRepresentationRawMenu         matlab.ui.container.Menu
        XRepresentationOffsetCorrectedMenu  matlab.ui.container.Menu
        XRepresentationProbeSampleDistanceMenu  matlab.ui.container.Menu
        YRepresentationMenu            matlab.ui.container.Menu
        YRepresentationRawMenu         matlab.ui.container.Menu
        YRepresentationOffsetCorrectedMenu  matlab.ui.container.Menu
        YRepresentationDeflectionMenu  matlab.ui.container.Menu
        YRepresentationForceMenu       matlab.ui.container.Menu
        ExternalFigureMenu             matlab.ui.container.Menu
        FZAnalysisMenu                 matlab.ui.container.Menu
        FZRampSelectionMenu            matlab.ui.container.Menu
        SelectMenu                     matlab.ui.container.Menu
        RampSelectCurrentMenu          matlab.ui.container.Menu
        RampSelectAllMenu              matlab.ui.container.Menu
        UnSelectMenu                   matlab.ui.container.Menu
        RampUnSelectCurrentMenu        matlab.ui.container.Menu
        RampUnSelectAllMenu            matlab.ui.container.Menu
        YOffsetMenu                    matlab.ui.container.Menu
        YOffsetAverageMenu             matlab.ui.container.Menu
        RampCalibrationMenu            matlab.ui.container.Menu
        XOffsetMenu                    matlab.ui.container.Menu
        XOffsetHertzModelMenu          matlab.ui.container.Menu
        LinearFitMenu                  matlab.ui.container.Menu
        ExponentialFitMenu             matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        ExportRampMenu                 matlab.ui.container.Menu
        ExportFSASFileMenu             matlab.ui.container.Menu
        RampAxes                       matlab.ui.control.UIAxes
        PrevButton                     matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        ZoomRampPanel                  matlab.ui.container.Panel
        XMinEditFieldLabel             matlab.ui.control.Label
        XMinZoomRampEditField          matlab.ui.control.NumericEditField
        XMaxEditFieldLabel             matlab.ui.control.Label
        XMaxZoomRampEditField          matlab.ui.control.NumericEditField
        YMinEditFieldLabel             matlab.ui.control.Label
        YMinZoomRampEditField          matlab.ui.control.NumericEditField
        YMaxEditFieldLabel             matlab.ui.control.Label
        YMaxZoomRampEditField          matlab.ui.control.NumericEditField
        RampZoomInButton               matlab.ui.control.Button
        RampZoomOutButton              matlab.ui.control.Button
        CurrentRampLabel               matlab.ui.control.Label
        CurrentRampTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        FV % ForceVolumeClass objectNumberOfRamps
        Selection
        Title
        RampDirection % 1: forward, 2: backward, 3: both
        CurrentRamp % Force Ramp indicator
        % Representation of Ramp X Axes:
        % 1: Raw
        % 2: Offset corrected
        % 3: Probe-Sample distance
        RampXRepresentation
        % Representation of Ramp Y Axes:
        % 1: Raw
        % 2: Offset corrected
        % 3: Deflection
        % 4: Force
        RampYRepresentation
    end
    
    properties (Access = public)
        AnalysisParameters % Description
    end
    
    methods (Access = private)
        
        function PlotRamp(app)
            % Function for plotting fz ramps in app.RampAxes
            % 
            % Text area wehre ramp parameters are listed is updated
            app.UpdateCurrentRampTextArea();
            % Axes are cleared
            cla(app.RampAxes, 'reset');
            % Initialize a counter for the number of simultanoeusly plotted arrays
            counterPlots = 0;
            % Initialize an array of the strings of each label for each
            % plotted data
            legendRamps = {};
            % Plots will only be shown if the corresponding
            % current representations in the X and Y axes are
            % available/calculated. Otherwise, an error message is
            % displayed in the axes
            if app.FV.Ramp{app.CurrentRamp,1}.FZRepresentationX(app.RampXRepresentation) == 1 &&...
                    app.FV.Ramp{app.CurrentRamp,1}.FZRepresentationY(app.RampYRepresentation) == 1
                % Two "if" statemes are declared, depending on the value app.RampDirection
                % it is decided whether to plot the forward, the backward or both curves
                if app.RampDirection==1 || app.RampDirection==3
                    % Plot the forward fz ramp
                    plot(app.RampAxes,...
                        app.FV.Ramp{app.CurrentRamp, 1}.XF(:,app.RampXRepresentation),...
                        app.FV.Ramp{app.CurrentRamp, 1}.YF(:,app.RampYRepresentation),...
                        'o','MarkerSize',1);
                    hold(app.RampAxes, 'on');
                    counterPlots = counterPlots +1;
                    legendRamps{counterPlots} = 'Forward';
                    % Plot the fit to the Hertz contact model
                    if app.RampXRepresentation ~= 1 && app.RampYRepresentation ~= 1 && app.RampYRepresentation ~= 2
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.CurrentRamp, 1}.HertzX(:,app.RampXRepresentation-1),...
                            app.FV.Ramp{app.CurrentRamp, 1}.HertzY(:,app.RampYRepresentation-2),...
                            'g','LineWidth',2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Hertz';
                    end
                    % Plot the linear fit
                    if app.FV.Ramp{app.CurrentRamp, 1}.AnalysisRepresentation(2) == 1 &&...
                            app.RampXRepresentation == app.FV.Ramp{app.CurrentRamp, 1}.linearFitRepresentationX &&...
                            app.RampYRepresentation == app.FV.Ramp{app.CurrentRamp, 1}.linearFitRepresentationY
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.CurrentRamp, 1}.LinearFitX,...
                            app.FV.Ramp{app.CurrentRamp, 1}.LinearFitY,...
                            'k', 'LineWidth', 2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Linear Fit';
                    end
                    % Plot the exponential fit
                    if app.RampXRepresentation == 3 && app.RampYRepresentation == 4 &&...
                            app.FV.Ramp{app.CurrentRamp, 1}.AnalysisRepresentation(3) == 1
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.CurrentRamp, 1}.ExpX,...
                            app.FV.Ramp{app.CurrentRamp, 1}.ExpY,...
                            'm','LineWidth',2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Exponential Fit';
                    end
                end
                if app.RampDirection==2 || app.RampDirection==3
                    % Plot the backward fz ramp
                    plot(app.RampAxes,...
                        app.FV.Ramp{app.CurrentRamp, 1}.XB(:,app.RampXRepresentation),...
                        app.FV.Ramp{app.CurrentRamp, 1}.YB(:,app.RampYRepresentation),...
                        'or','MarkerSize',1);
                    counterPlots = counterPlots +1;
                    legendRamps{counterPlots} = 'Backward';
                end
                switch app.RampXRepresentation
                    case 1
                        xlabel(app.RampAxes, "Sample Vertical Displacement (nm)");
                    case 2
                        xlabel(app.RampAxes, "Sample Vertical Displacement (nm) Offset Corrected");
                    case 3
                        xlabel(app.RampAxes, "Probe-Sample Distance (nm)");
                end
                % Sets correct label for x axis
                switch app.RampYRepresentation
                    case 1
                        ylabel(app.RampAxes, "Photodetector Signal (V)");
                    case 2
                        ylabel(app.RampAxes, "Photodetector Signal (V) Offset Corrected");
                    case 3
                        ylabel(app.RampAxes, "Deflection (nm)");
                    case 4
                        ylabel(app.RampAxes, "Force (nN)");
                end
                app.RampAxes.Title.String = app.FV.Ramp{app.CurrentRamp, 1}.Title;
                grid(app.RampAxes, 'on');
                legend(app.RampAxes, legendRamps);
            else
                text(app.RampAxes, 0.2, 0.5, ...
                    'FZ not available in the current representation');
            end
        end
        
        function CalculateSelection(app)
            for i = 1:app.FV.NumberOfMapRows
                app.Selection(i, 1) =...
                        app.FV.Ramp{i, 1}.Property(4);
            end            
        end
        
        function UpdateCurrentRampTextArea(app)
            line1 = 'Selected: %d';
            cellArrayText{1} =...
                sprintf(line1, app.Selection(app.CurrentRamp));
            line2 = 'Slope: %d';
            cellArrayText{2} =...
                sprintf(line2, app.FV.Ramp{app.CurrentRamp,1}.Property(5));
            line3 = 'Young Modulus (GPa): %d';
            cellArrayText{3} =...
                sprintf(line3, app.FV.Ramp{app.CurrentRamp,1}.Property(6));
            line4 = 'Adhesion Force (nN): %d';
            cellArrayText{4} =...
                sprintf(line4, app.FV.Ramp{app.CurrentRamp,1}.Property(8));
            line5 = 'Adhesion Work (nm*nN): %d';
            cellArrayText{5} =...
                sprintf(line5, app.FV.Ramp{app.CurrentRamp,1}.Property(9));
            line6 = 'Exponential Amplitude (nN): %d';
            cellArrayText{6} =...
                sprintf(line6, app.FV.Ramp{app.CurrentRamp,1}.Property(10));
            line7 = 'Exponential Length (nm): %d';
            cellArrayText{7} =...
                sprintf(line7, app.FV.Ramp{app.CurrentRamp,1}.Property(11));
            app.CurrentRampTextArea.Value = cellArrayText;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FileNames, FilePath, callOption, storage)
            switch callOption
                case 1
                    file=fullfile(FilePath,FileNames);
                    a = load(file);
                    app.FV = a.vargin;
                case 2
                    app.FV = ForceVolumeClass(FileNames, FilePath, 2, storage);
            end
            app.CalculateSelection();
            app.RampDirection = 3;
            app.CurrentRamp = 1;
            app.RampXRepresentation = 1;
            app.RampYRepresentation = 1;
            app.PlotRamp();            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.CurrentRamp == app.FV.NumberOfMapRows
                app.CurrentRamp = 1;
            else
                app.CurrentRamp = app.CurrentRamp+1;
            end
            app.PlotRamp();
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if app.CurrentRamp == 1
                app.CurrentRamp = app.FV.NumberOfMapRows;
            else
                app.CurrentRamp = app.CurrentRamp-1;
            end
            app.PlotRamp();
        end

        % Menu selected function: ForwardMenu
        function ForwardMenuSelected(app, event)
            app.RampDirection = 1;
            app.PlotRamp();
        end

        % Menu selected function: BackwardMenu
        function BackwardMenuSelected(app, event)
            app.RampDirection = 2;
            app.PlotRamp();
        end

        % Menu selected function: BothMenu
        function BothMenuSelected(app, event)
            app.RampDirection = 3;
            app.PlotRamp();
        end

        % Menu selected function: XRepresentationRawMenu
        function XRepresentationRawMenuSelected(app, event)
            app.RampXRepresentation = 1;
            app.PlotRamp();
        end

        % Menu selected function: XRepresentationOffsetCorrectedMenu
        function XRepresentationOffsetCorrectedMenuSelected(app, event)
            app.RampXRepresentation = 2;
            app.PlotRamp();
        end

        % Menu selected function: 
        % XRepresentationProbeSampleDistanceMenu
        function XRepresentationProbeSampleDistanceMenuSelected(app, event)
            app.RampXRepresentation = 3;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationRawMenu
        function YRepresentationRawMenuSelected(app, event)
            app.RampYRepresentation = 1;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationOffsetCorrectedMenu
        function YRepresentationOffsetCorrectedMenuSelected(app, event)
            app.RampYRepresentation = 2;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationDeflectionMenu
        function YRepresentationDeflectionMenuSelected(app, event)
            app.RampYRepresentation = 3;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationForceMenu
        function YRepresentationForceMenuSelected(app, event)
            app.RampYRepresentation = 4;
            app.PlotRamp();
        end

        % Menu selected function: RampSelectCurrentMenu
        function RampSelectCurrentMenuSelected(app, event)
            app.FV.Ramp{app.CurrentRamp, 1}.Property(4) = 1;
            app.CalculateSelection();
            app.PlotRamp();
        end

        % Menu selected function: RampSelectAllMenu
        function RampSelectAllMenuSelected(app, event)
            for i=1:app.FV.NumberOfMapRows
                app.FV.Ramp{i, 1}.Property(4) = 1;
            end
            app.CalculateSelection();
            app.PlotRamp();
        end

        % Menu selected function: RampUnSelectCurrentMenu
        function RampUnSelectCurrentMenuSelected(app, event)
            app.FV.Ramp{app.CurrentRamp, 1}.Property(4) = 0;
            app.CalculateSelection();
            app.PlotRamp();
        end

        % Menu selected function: RampUnSelectAllMenu
        function RampUnSelectAllMenuSelected(app, event)
            for i=1:app.FV.NumberOfMapRows
                app.FV.Ramp{i, 1}.Property(4) = 0;
            end
            app.CalculateSelection();
            app.PlotRamp();
        end

        % Menu selected function: YOffsetAverageMenu
        function YOffsetAverageMenuSelected(app, event)
            % Substracts an Y offset to the raw FZ ramps            
            %
            % Change the Ramp Y Representation to 1 (photodetector signal)
            app.RampYRepresentation = 1;
            app.PlotRamp();
            % Call the Parameters App that will return the parameters xmin
            % and xmax defining the range used for determining Y offset
            % ParametersApp can be used to retrieve different sets of
            % parameters. Y offset is called by using the call variable
            % "YOffset"
            ParametersApp(app, "YOffset");
            uiwait(app.UIFigure);
            % Call a ForceVolumeClass method for Y offset substraction
            app.FV.YOffsetAverage(app.Selection, app.AnalysisParameters);
            % Change the Ramp Y Representation to 2 (offset corrected) before plotting
            % the ramps
            app.RampYRepresentation = 2;
            app.PlotRamp();
        end

        % Menu selected function: RampCalibrationMenu
        function RampCalibrationMenuSelected(app, event)
            % Substracts an Y offset to the raw FZ ramp            
            
            % Check if all ramps in range had their photodetector values
            % already offset corrected
            checker = 1;
            [row, col, ~] = find(app.Selection);
            for i = 1:length(row)
                if app.FV.Ramp{row(i),col(i)}.FZRepresentationY(2) == 0
                    checker = 0;
                    break;
                end                
            end
            
            % If all the specificed ramps had they photodetector signal
            % values offset corrected, the calibration process continues.
            % Otherwise, an error window is called.
            if checker == 1
                % Call the Parameters App that will return the parameters used
                % for ramp calibration
                RampCalibrationParametersApp(app);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
                % Call a ForceVolumeClass method for Y offset substraction
                app.FV.RampCalibration(app.Selection, app.AnalysisParameters);
                % Change the Ramp Y Representation to 2 (offset corrected)
                app.RampYRepresentation = 4;
                app.PlotRamp();
            else
                % Call the Error Window with specific message
                errorlabel = 'Not all specified ramps had their\nphotodetector signal values offset corrected';
                errorlabel = compose(errorlabel);
                ErrorWindow(app, errorlabel);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
            end
        end

        % Menu selected function: XOffsetHertzModelMenu
        function XOffsetHertzModelMenuSelected(app, event)
            % Finds the contact point according to the Hertz Model,
            % and substracts it for the Sample Vertical Positions.           
            % It also calculates de Young modulus for each ramp, and
            % creates the corresponding map.
            
            % Check if all ramps in range had their photodetector values
            % converted into cantilever deflection values
            checker = 1;
            [row, col, ~] = find(app.Selection);
            for i = 1:length(row)
                if app.FV.Ramp{row(i),col(i)}.FZRepresentationY(3) == 0
                    checker = 0;
                    break;
                end                
            end
            
            % If all the specificed ramps had their cantilever deflection
            % values calculated, the X Offset process continues.
            % Otherwise, an error window is called.
            if checker ==1
                % The current force ramp is plotted in a offset corrected
                % photodetector values vs. sample displacement
                % representation:
                app.RampYRepresentation = 2;
                app.RampXRepresentation = 1;
                app.PlotRamp();
                
                % Call the external app "ParametersApp" which will let the
                % user define the XF(:,2) range that will be used for Hertz
                % fitting. The minimim and maximum values defining the
                % range will be stored in AnalysisParameters.xForwardMin
                % and AnalysisParameters.xForwardMax.
                ParametersApp(app, "HertzXOffset");
                
                % The app will wait until "ParametersApp" returns values
                % for AnalysisParameters.xForwardMin and 
                % AnalysisParameters.xForwardMax.
                uiwait(app.UIFigure);
                
                % Call a ForceVolumeClass method for Y offset substraction
                app.FV.HertzXOffset(app.Selection, app.AnalysisParameters);
                
                % Change the Ramp Y Representation to 3 (cantilever deflection) and the
                % Ramp X Representation to 2 (offset corrected) before plotting
                % the ramps
                app.RampYRepresentation = 3;
                app.RampXRepresentation = 2;
                app.PlotRamp();
            else
                % Call the Error Window with specific message
                errorlabel = 'Not all specified ramps had their photodetector signal values\nconverted into cantilever deflection units';
                errorlabel = compose(errorlabel);
                ErrorWindow(app, errorlabel);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
            end
        end

        % Menu selected function: LinearFitMenu
        function LinearFitMenuSelected(app, event)
            % Call an app used by the user to define the distance range
            % that will be used for the exponential fit
            ParametersApp(app, "LinearFit");
            uiwait(app.UIFigure);
            % Call a ForceVolumeClass method for exponential fit of
            % the selected probe-sample distance range 
            app.FV.LinearFit(app.Selection, app.AnalysisParameters,...
                app.RampXRepresentation, app.RampYRepresentation);
            % Actualize the ramp plot
            app.PlotRamp();
        end

        % Menu selected function: ExponentialFitMenu
        function ExponentialFitMenuSelected(app, event)
            % Fits with an exponential function the foce vs. probe-sample
            % distance represenation of force ramps (over a range defined
            % by the user.
            
            % Check if all selected ramps in range had their probe-sample distance
            % representation calculated
            checker = 1;
            [row, col, ~] = find(app.Selection);
            for i = 1:length(row)
                if app.FV.Ramp{row(i),col(i)}.FZRepresentationX(3) == 0
                    checker = 0;
                    break;
                end                
            end
            
            % If all the selected ramps had their probe-sample distance
            % representation calculated, the process continues.
            % Otherwise, an error window is called.
            if checker == 1
                % First, the current force ramp is plotted in a force vs 
                % probe-sample distance representation
                app.RampXRepresentation = 3;
                app.RampYRepresentation = 4;
                app.PlotRamp();
                
                % Call an app used by the user to define the distance range
                % that will be used for the exponential fit
                ParametersApp(app, "ExponentialFit");
                uiwait(app.UIFigure);
                
                % Call an app used by the user to provide estimates of
                % minimum, start and masimum values of fit parameters
                FitParametersApp(app, "ExponentialFit");
                uiwait(app.UIFigure);
                
                % Call a ForceVolumeClass method for exponential fit of
                % the selected probe-sample distance range 
                app.FV.ExponentialFit(app.Selection, app.AnalysisParameters);
                
                % Plot ramp (this will actualize the plot with the
                % exponential fit
                app.PlotRamp();
            else
                % Call the Error Window with specific message
                errorlabel = 'Not all selected force ramps had their \nprobe-sample distance representation calculated';
                errorlabel = compose(errorlabel);
                ErrorWindow(app, errorlabel);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
            end
        end

        % Button pushed function: RampZoomOutButton
        function RampZoomOutButtonPushed(app, event)
            % Zooms out the ramp axes so that all ramp is visualized
            %
            %First, the max and min values in both x and y axis are found
            ZoomMinX = min(min(app.FV.Ramp{app.CurrentRamp,1}.XF(:,app.RampXRepresentation)),...
                min(app.FV.Ramp{app.CurrentRamp,1}.XB(:,app.RampXRepresentation)));
            ZoomMaxX = max(max(app.FV.Ramp{app.CurrentRamp,1}.XF(:,app.RampXRepresentation)),...
                max(app.FV.Ramp{app.CurrentRamp,1}.XB(:,app.RampXRepresentation)));
            ZoomMinY = min(min(app.FV.Ramp{app.CurrentRamp,1}.YF(:,app.RampYRepresentation)),...
                min(app.FV.Ramp{app.CurrentRamp,1}.YB(:,app.RampYRepresentation)));
            ZoomMaxY = max(max(app.FV.Ramp{app.CurrentRamp,1}.YF(:,app.RampYRepresentation)),...
                max(app.FV.Ramp{app.CurrentRamp,1}.YB(:,app.RampYRepresentation)));
            % Then, the whole range in x and y axis is calculated, and 10%
            % of this value is added to the min and max value of the axis
            % so that all ramp can be visualized
            rangeX = ZoomMaxX - ZoomMinX;
            rangeY = ZoomMaxY - ZoomMinY;
            ZoomMinX = ZoomMinX - rangeX * 0.1;
            ZoomMaxX = ZoomMaxX + rangeX * 0.1;
            ZoomMinY = ZoomMinY - rangeY * 0.1;
            ZoomMaxY = ZoomMaxY + rangeY * 0.1;
            axis(app.RampAxes, [ZoomMinX ZoomMaxX ZoomMinY ZoomMaxY]);
        end

        % Button pushed function: RampZoomInButton
        function RampZoomInButtonPushed(app, event)
            ZoomMinX = app.XMinZoomRampEditField.Value;
            ZoomMaxX = app.XMaxZoomRampEditField.Value;
            ZoomMinY = app.YMinZoomRampEditField.Value;
            ZoomMaxY = app.YMaxZoomRampEditField.Value;
            axis(app.RampAxes, [ZoomMinX ZoomMaxX ZoomMinY ZoomMaxY]);
        end

        % Menu selected function: ExternalFigureMenu
        function ExternalFigureMenuSelected(app, event)
            PlotRampInExternalFigure(app.FV.Ramp{app.CurrentRamp,1},...
                app.RampXRepresentation, app.RampYRepresentation, app.RampDirection);
        end

        % Menu selected function: ExportRampMenu
        function ExportRampMenuSelected(app, event)
            SaveStructure(app.FV.Ramp{app.CurrentRamp,1}, 'Save Ramp as:');
        end

        % Menu selected function: ExportFSASFileMenu
        function ExportFSASFileMenuSelected(app, event)
            SaveStructure(app.FV, 'Save Ramps FSAS file as:');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FSAS Force Ramp Analysis';
            app.FZViewMenu = uimenu(app.UIFigure);
            app.FZViewMenu.Text = 'FZ View';
            app.ScanDirectionMenu = uimenu(app.FZViewMenu);
            app.ScanDirectionMenu.Text = 'Scan Direction';
            app.ForwardMenu = uimenu(app.ScanDirectionMenu);
            app.ForwardMenu.MenuSelectedFcn = createCallbackFcn(app, @ForwardMenuSelected, true);
            app.ForwardMenu.Text = 'Forward';
            app.BackwardMenu = uimenu(app.ScanDirectionMenu);
            app.BackwardMenu.MenuSelectedFcn = createCallbackFcn(app, @BackwardMenuSelected, true);
            app.BackwardMenu.Text = 'Backward';
            app.BothMenu = uimenu(app.ScanDirectionMenu);
            app.BothMenu.MenuSelectedFcn = createCallbackFcn(app, @BothMenuSelected, true);
            app.BothMenu.Text = 'Both';
            app.XRepresentationMenu = uimenu(app.FZViewMenu);
            app.XRepresentationMenu.Text = 'X Representation';
            app.XRepresentationRawMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationRawMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationRawMenuSelected, true);
            app.XRepresentationRawMenu.Text = 'Raw';
            app.XRepresentationOffsetCorrectedMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationOffsetCorrectedMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationOffsetCorrectedMenuSelected, true);
            app.XRepresentationOffsetCorrectedMenu.Text = 'Offset Corrected';
            app.XRepresentationProbeSampleDistanceMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationProbeSampleDistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationProbeSampleDistanceMenuSelected, true);
            app.XRepresentationProbeSampleDistanceMenu.Text = 'Probe-Sample Distance';
            app.YRepresentationMenu = uimenu(app.FZViewMenu);
            app.YRepresentationMenu.Text = 'Y Representation';
            app.YRepresentationRawMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationRawMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationRawMenuSelected, true);
            app.YRepresentationRawMenu.Text = 'Raw';
            app.YRepresentationOffsetCorrectedMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationOffsetCorrectedMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationOffsetCorrectedMenuSelected, true);
            app.YRepresentationOffsetCorrectedMenu.Text = 'Offset Corrected';
            app.YRepresentationDeflectionMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationDeflectionMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationDeflectionMenuSelected, true);
            app.YRepresentationDeflectionMenu.Text = 'Deflection';
            app.YRepresentationForceMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationForceMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationForceMenuSelected, true);
            app.YRepresentationForceMenu.Text = 'Force';
            app.ExternalFigureMenu = uimenu(app.FZViewMenu);
            app.ExternalFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalFigureMenuSelected, true);
            app.ExternalFigureMenu.Text = 'External Figure';
            app.FZAnalysisMenu = uimenu(app.UIFigure);
            app.FZAnalysisMenu.Text = 'FZ Analysis';
            app.FZRampSelectionMenu = uimenu(app.FZAnalysisMenu);
            app.FZRampSelectionMenu.Text = 'Selection';
            app.SelectMenu = uimenu(app.FZRampSelectionMenu);
            app.SelectMenu.Text = 'Select';
            app.RampSelectCurrentMenu = uimenu(app.SelectMenu);
            app.RampSelectCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @RampSelectCurrentMenuSelected, true);
            app.RampSelectCurrentMenu.Text = 'Current';
            app.RampSelectAllMenu = uimenu(app.SelectMenu);
            app.RampSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @RampSelectAllMenuSelected, true);
            app.RampSelectAllMenu.Text = 'All';
            app.UnSelectMenu = uimenu(app.FZRampSelectionMenu);
            app.UnSelectMenu.Text = 'UnSelect';
            app.RampUnSelectCurrentMenu = uimenu(app.UnSelectMenu);
            app.RampUnSelectCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @RampUnSelectCurrentMenuSelected, true);
            app.RampUnSelectCurrentMenu.Text = 'Current';
            app.RampUnSelectAllMenu = uimenu(app.UnSelectMenu);
            app.RampUnSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @RampUnSelectAllMenuSelected, true);
            app.RampUnSelectAllMenu.Text = 'All';
            app.YOffsetMenu = uimenu(app.FZAnalysisMenu);
            app.YOffsetMenu.Text = 'Y Offset';
            app.YOffsetAverageMenu = uimenu(app.YOffsetMenu);
            app.YOffsetAverageMenu.MenuSelectedFcn = createCallbackFcn(app, @YOffsetAverageMenuSelected, true);
            app.YOffsetAverageMenu.Text = 'Average';
            app.RampCalibrationMenu = uimenu(app.FZAnalysisMenu);
            app.RampCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @RampCalibrationMenuSelected, true);
            app.RampCalibrationMenu.Text = 'Ramp Calibration';
            app.XOffsetMenu = uimenu(app.FZAnalysisMenu);
            app.XOffsetMenu.Text = 'X Offset';
            app.XOffsetHertzModelMenu = uimenu(app.XOffsetMenu);
            app.XOffsetHertzModelMenu.MenuSelectedFcn = createCallbackFcn(app, @XOffsetHertzModelMenuSelected, true);
            app.XOffsetHertzModelMenu.Text = 'Hertz Model';
            app.LinearFitMenu = uimenu(app.FZAnalysisMenu);
            app.LinearFitMenu.MenuSelectedFcn = createCallbackFcn(app, @LinearFitMenuSelected, true);
            app.LinearFitMenu.Text = 'LinearFit';
            app.ExponentialFitMenu = uimenu(app.FZAnalysisMenu);
            app.ExponentialFitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExponentialFitMenuSelected, true);
            app.ExponentialFitMenu.Text = 'Exponential Fit';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportRampMenu = uimenu(app.ExportMenu);
            app.ExportRampMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportRampMenuSelected, true);
            app.ExportRampMenu.Text = 'Ramp';
            app.ExportFSASFileMenu = uimenu(app.ExportMenu);
            app.ExportFSASFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFSASFileMenuSelected, true);
            app.ExportFSASFileMenu.Text = 'FSAS';
            app.RampAxes = uiaxes(app.UIFigure);
            title(app.RampAxes, 'Title')
            xlabel(app.RampAxes, 'X')
            ylabel(app.RampAxes, 'Y')
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ZoomRampPanel = uipanel(app.UIFigure);
            app.ZoomRampPanel.Title = 'Zoom Ramp';
            app.XMinEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.XMinEditFieldLabel.Text = 'X Min';
            app.XMinZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.XMaxEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.XMaxEditFieldLabel.Text = 'X Max';
            app.XMaxZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.YMinEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.YMinEditFieldLabel.Text = 'Y Min';
            app.YMinZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.YMaxEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.YMaxEditFieldLabel.Text = 'Y Max';
            app.YMaxZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.RampZoomInButton = uibutton(app.ZoomRampPanel, 'push');
            app.RampZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @RampZoomInButtonPushed, true);
            app.RampZoomInButton.Text = {'Zoom'; 'In'};
            app.RampZoomOutButton = uibutton(app.ZoomRampPanel, 'push');
            app.RampZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @RampZoomOutButtonPushed, true);
            app.RampZoomOutButton.Text = {'Zoom'; 'Out'};
            app.CurrentRampLabel = uilabel(app.UIFigure);
            app.CurrentRampLabel.Text = {'Current'; 'Ramp'};
            app.CurrentRampTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceRampAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end