classdef ForceVolumeAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FZViewMenu                      matlab.ui.container.Menu
        ScanDirectionMenu               matlab.ui.container.Menu
        ForwardMenu                     matlab.ui.container.Menu
        BackwardMenu                    matlab.ui.container.Menu
        BothMenu                        matlab.ui.container.Menu
        XRepresentationMenu             matlab.ui.container.Menu
        XRepresentationRawMenu          matlab.ui.container.Menu
        XRepresentationOffsetCorrectedMenu  matlab.ui.container.Menu
        XRepresentationProbeSampleDistanceMenu  matlab.ui.container.Menu
        YRepresentationMenu             matlab.ui.container.Menu
        YRepresentationRawMenu          matlab.ui.container.Menu
        YRepresentationOffsetCorrectedMenu  matlab.ui.container.Menu
        YRepresentationDeflectionMenu   matlab.ui.container.Menu
        YRepresentationForceMenu        matlab.ui.container.Menu
        ExternalFigureMenu              matlab.ui.container.Menu
        FZAnalysisMenu                  matlab.ui.container.Menu
        YOffsetMenu                     matlab.ui.container.Menu
        YOffsetAverageMenu              matlab.ui.container.Menu
        RampCalibrationMenu             matlab.ui.container.Menu
        XOffsetMenu                     matlab.ui.container.Menu
        XOffsetHertzModelMenu           matlab.ui.container.Menu
        LinearFitMenu                   matlab.ui.container.Menu
        ExponentialFitMenu              matlab.ui.container.Menu
        MapViewMenu                     matlab.ui.container.Menu
        MapViewTopographyMenu           matlab.ui.container.Menu
        MapViewSelectionMenu            matlab.ui.container.Menu
        MapViewSlopeMenu                matlab.ui.container.Menu
        MapViewYoungModulusMenu         matlab.ui.container.Menu
        MapViewMaximumAdhesionMenu      matlab.ui.container.Menu
        MapViewAdhesionWorkMenu         matlab.ui.container.Menu
        MapViewExponentialAmplitudeMenu  matlab.ui.container.Menu
        MapViewExponentialLengthMenu    matlab.ui.container.Menu
        MapAnalysisMenu                 matlab.ui.container.Menu
        SelectionMenu                   matlab.ui.container.Menu
        SelectMenu                      matlab.ui.container.Menu
        SelectCurrentMenu               matlab.ui.container.Menu
        SelectAllMenu                   matlab.ui.container.Menu
        UnSelectMenu                    matlab.ui.container.Menu
        UnSelectCurrentMenu             matlab.ui.container.Menu
        UnSelectAllMenu                 matlab.ui.container.Menu
        HistogramMenu                   matlab.ui.container.Menu
        MapHistStandardMenu             matlab.ui.container.Menu
        MapHistCustomMenu               matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        MatlabStructureMenu             matlab.ui.container.Menu
        ExportStructureRampMenu         matlab.ui.container.Menu
        ExportStructureMapMenu          matlab.ui.container.Menu
        ExportStructureForceVolumeMenu  matlab.ui.container.Menu
        RampAxes                        matlab.ui.control.UIAxes
        MapAxes                         matlab.ui.control.UIAxes
        ZoomRampPanel                   matlab.ui.container.Panel
        XMinEditFieldLabel              matlab.ui.control.Label
        XMinZoomRampEditField           matlab.ui.control.NumericEditField
        XMaxEditFieldLabel              matlab.ui.control.Label
        XMaxZoomRampEditField           matlab.ui.control.NumericEditField
        YMinEditFieldLabel              matlab.ui.control.Label
        YMinZoomRampEditField           matlab.ui.control.NumericEditField
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxZoomRampEditField           matlab.ui.control.NumericEditField
        RampZoomInButton                matlab.ui.control.Button
        RampZoomOutButton               matlab.ui.control.Button
        MapNavigationPanel              matlab.ui.container.Panel
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        CurrentRampTextAreaLabel        matlab.ui.control.Label
        CurrentRampTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % FV: ForceVolumeClass object
        FV
        % RampDirection: Index of ramp directions. Possible values
        % 1 -> forward
        % 2 -> backward
        % 3 -> both
        RampDirection
        % ActualX: Current X Pixel
        ActualX
        % ActualY: Current Y Pixel
        ActualY
        % RampXRepresentation: Representation of Ramp X Axes:
        % 1 -> Raw
        % 2 -> Offset corrected
        % 3 -> Probe-Sample distance
        RampXRepresentation
        % RampYRepresentation: Representation of Ramp Y Axes:
        % 1 -> Raw
        % 2 -> Offset corrected
        % 3 -> Deflection
        % 4 -> Force
        RampYRepresentation
        % Map: Representation of maps
        Map
        % MapRepresentation: Index for current displayed map representation
        % 1 -> Topography/Height
        % 2 -> Selected points
        % 3 -> Slope
        % 4 -> Young Modulus
        % 5 -> Maximum adhesion
        % 6 -> Adhesion work
        % 7 -> Exponential amplitude
        % 8 -> Exponential length
        MapRepresentation
    end
    
    properties (Access = public)
        % AnalysisParameters -> Structure with fields that are used 
        % for analysis of force ramps.
        % The values for the different fields are received from the
        % external apps ParametersApp.mlapp and FitParametersApp.mlapp.
        % From ParametersApp.mlapp, values for the following fields of 
        % AnalysisParameters can be set:
        %   - xForwardMin, xForwardMax, xBackwardMin, xBackwardMax:
        %   definition of minimum and maximum values of x-axis of interest
        %   for analysis.
        %   - HistMin, HistMax, HistBins: used for plotting data from a map
        %   as a histogram in an external figure.
        % From FitParametersApp.mlapp, values for the following fields of 
        % AnalysisParameters can be set:
        %   - MinP1, StartP1, MaxP1
        %   - MinP2, StartP2, MaxP2
        %   - MinP3, StartP3, MaxP3
        %   - MinP4, StartP4, MaxP4
        % The above correspond to the limits and starting values for
        % different fit parameters used in fits of the ramps to different
        % functions
        AnalysisParameters
    end
    
    methods (Access = private)
        
        function PlotRamp(app)
            % Function for plotting fz ramps in app.RampAxes
            % 
            % Text area wehre ramp parameters are listed is updated
            app.UpdateCurrentRampTextArea();
            % Ramp axes are cleared
            cla(app.RampAxes, 'reset');
            % Initialize a counter for the number of simultanoeusly plotted arrays
            counterPlots = 0;
            % Initialize an array of the strings of each label for each
            % plotted data
            legendRamps = {};
            % Plots will only be shown if the corresponding
            % current representations in the X and Y axes are
            % available/calculated. Otherwise, an error message is
            % displayed in the axes
            if app.FV.Ramp{app.ActualX,app.ActualY}.FZRepresentationX(app.RampXRepresentation) == 1 &&...
                        app.FV.Ramp{app.ActualX,app.ActualY}.FZRepresentationY(app.RampYRepresentation) == 1
                % Two "if" statemes are declared, depending on the value app.RampDirection
                % it is decided whether to plot the forward, the backward or both curves 
                if app.RampDirection==1 || app.RampDirection==3
                    % Plot the forward fz ramp
                    plot(app.RampAxes,...
                        app.FV.Ramp{app.ActualX,app.ActualY}.XF(:,app.RampXRepresentation),...
                        app.FV.Ramp{app.ActualX,app.ActualY}.YF(:,app.RampYRepresentation),...
                        'o','MarkerSize',1);
                    counterPlots = counterPlots +1;
                    legendRamps{counterPlots} = 'Forward';
                    hold(app.RampAxes, 'on');
                    % Plot the fit to the Hertz contact model
                    if app.RampXRepresentation ~= 1 && app.RampYRepresentation ~= 1 && app.RampYRepresentation ~= 2
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.ActualX,app.ActualY}.HertzX(:,app.RampXRepresentation-1),...
                            app.FV.Ramp{app.ActualX,app.ActualY}.HertzY(:,app.RampYRepresentation-2),...
                            'g','LineWidth',2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Hertz';
                    end
                    % Plot the linear fit
                    if app.FV.Ramp{app.ActualX,app.ActualY}.AnalysisRepresentation(2) == 1 &&...
                            app.RampXRepresentation == app.FV.Ramp{app.ActualX,app.ActualY}.linearFitRepresentationX &&...
                            app.RampYRepresentation == app.FV.Ramp{app.ActualX,app.ActualY}.linearFitRepresentationY
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.ActualX,app.ActualY}.LinearFitX,...
                            app.FV.Ramp{app.ActualX,app.ActualY}.LinearFitY,...
                            'k', 'LineWidth', 2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Linear Fit';
                    end
                    % Plot the exponential fit
                    if app.RampXRepresentation == 3 && app.RampYRepresentation == 4 &&...
                            app.FV.Ramp{app.ActualX,app.ActualY}.AnalysisRepresentation(3) == 1
                        plot(app.RampAxes,...
                            app.FV.Ramp{app.ActualX,app.ActualY}.ExpX,...
                            app.FV.Ramp{app.ActualX,app.ActualY}.ExpY,...
                            'm','LineWidth',2);
                        counterPlots = counterPlots +1;
                        legendRamps{counterPlots} = 'Exponential Fit';
                    end
                end
                if app.RampDirection==2 || app.RampDirection==3
                    % Plot the backward fz ramp
                    plot(app.RampAxes,...
                        app.FV.Ramp{app.ActualX,app.ActualY}.XB(:,app.RampXRepresentation),...
                        app.FV.Ramp{app.ActualX,app.ActualY}.YB(:,app.RampYRepresentation),...
                        'or','MarkerSize',1);
                    counterPlots = counterPlots +1;
                    legendRamps{counterPlots} = 'Backward';
                end
                % Sets correct label for x axis
                switch app.RampXRepresentation
                    case 1
                        xlabel(app.RampAxes, "Sample Vertical Displacement (nm)");
                    case 2
                        xlabel(app.RampAxes, "Sample Vertical Displacement (nm) Offset Corrected");
                    case 3
                        xlabel(app.RampAxes, "Probe-Sample Distance (nm)");
                end
                % Sets correct label for x axis
                switch app.RampYRepresentation
                    case 1
                        ylabel(app.RampAxes, "Photodetector Signal (V)");
                    case 2
                        ylabel(app.RampAxes, "Photodetector Signal (V) Offset Corrected");
                    case 3
                        ylabel(app.RampAxes, "Deflection (nm)");
                    case 4
                        ylabel(app.RampAxes, "Force (nN)");
                end
                grid(app.RampAxes, 'on');
                legend(app.RampAxes, legendRamps);
            else
                text(app.RampAxes, 0.2, 0.5, ...
                    'FZ not available in the current representation');
            end            
        end
        
        function PlotMap(app)
            hold(app.MapAxes, 'off');
            rangex = 1:app.FV.NumberOfMapRows;
            rangey = 1:app.FV.NumberOfMapColumns;
            colormap(app.MapAxes, 'hot')
            imagesc(app.MapAxes,...
                app.Map(app.MapRepresentation).Value(rangex, rangey));
            hold(app.MapAxes, 'on');
            % Note that X and Y are inverted below, but this is just for
            % representation
            plot(app.MapAxes,...
                app.ActualY, app.ActualX,'bo', 'MarkerSize', 6, 'MarkerFaceColor', 'b');
            colorbar(app.MapAxes,"eastoutside");
            app.MapAxes.XLim = [0.5 app.FV.NumberOfMapRows+0.5];
            app.MapAxes.YLim = [0.5 app.FV.NumberOfMapColumns+0.5];
            app.MapAxes.XTick = [0.5 app.FV.NumberOfMapRows+0.5];
            app.MapAxes.YTick = 0.5;
            app.MapAxes.XTickLabel = {'0' num2str(app.FV.MapLength)};
            app.MapAxes.YTickLabel = {num2str(app.FV.MapLength)};
            app.MapAxes.XLabel.String = 'X (nm)';
            app.MapAxes.YLabel.String = 'Y (nm)';
            
            switch app.MapRepresentation
                case 3
                    app.MapAxes.Title.String = 'Topography';
                case 4
                    app.MapAxes.Title.String = 'Selection';
                case 5
                    app.MapAxes.Title.String = 'Slope';
                case 6
                    app.MapAxes.Title.String = 'Young Modulus';
                case 7
                    app.MapAxes.Title.String = 'Maximum Adhesion';
                case 8
                    app.MapAxes.Title.String = 'Adhesion ';
                case 9
                    app.MapAxes.Title.String = 'Adhesion Work';
                case 10
                    app.MapAxes.Title.String = 'Exponential Amplitude';
                case 11
                    app.MapAxes.Title.String = 'Exponential Length';
            end
        end
        
        function CalculateMap(app, mIndex)
            for NX = 1:app.FV.NumberOfMapRows
                for NY = 1:1:app.FV.NumberOfMapColumns
                    app.Map(mIndex).Value(NX, NY) =...
                        app.FV.Ramp{NX, NY}.Property(mIndex);
                end
            end            
        end
        
        
        function UpdateCurrentRampTextArea(app)
            cellArrayText{1} = 'Selected:';
            if app.FV.Ramp{app.ActualX, app.ActualY}.Property(4) == 1
                cellArrayText{2} = 'Yes';
            else
                cellArrayText{2} = 'No';
            end
            cellArrayText{3} = 'Slope:';
            cellArrayText{4} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(5));
            cellArrayText{5} = 'Young Modulus (GPa):';
            cellArrayText{6} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(6));
            cellArrayText{7} = 'Adhesion Force (nN):';
            cellArrayText{8} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(8));
            cellArrayText{9} = 'Adhesion Work (nm*nN):';
            cellArrayText{10} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(9));
            cellArrayText{11} = 'Exponential Amplitude (nN):';
            cellArrayText{12} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(10));
            cellArrayText{13} = 'Exponential Length (nm):';
            cellArrayText{14} = num2str(app.FV.Ramp{app.ActualX, app.ActualY}.Property(11));
            app.CurrentRampTextArea.Value = cellArrayText;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FileName, FilePath, callOption)
            switch callOption
                case 1
                    file=fullfile(FilePath,FileName);
                    a = load(file);
                    app.FV = a.vargin;
                case 2
                    app.FV = ForceVolumeClass(FileName, FilePath, 1);
            end
            
            for i = 1:11
                CalculateMap(app, i)
            end
            app.RampDirection = 3;
            app.ActualX = 1;
            app.ActualY = 1;
            app.RampXRepresentation = 1;
            app.RampYRepresentation = 1;
            app.MapRepresentation = 3;
            app.PlotRamp();
            app.PlotMap();
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.ActualX == 1
                app.ActualX = app.FV.NumberOfMapRows;
            else 
                app.ActualX = app.ActualX - 1;
            end
            app.PlotRamp();
            app.PlotMap();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.ActualX == app.FV.NumberOfMapRows
                app.ActualX = 1;
            else
                app.ActualX = app.ActualX+1;
            end
            app.PlotRamp();
            app.PlotMap();
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.ActualY == app.FV.NumberOfMapColumns
                app.ActualY = 1;
            else
                app.ActualY = app.ActualY + 1;
            end
            app.PlotRamp();
            app.PlotMap();
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.ActualY == 1
                app.ActualY = app.FV.NumberOfMapColumns;
            else 
                app.ActualY = app.ActualY - 1;
            end
            app.PlotRamp();
            app.PlotMap();
        end

        % Menu selected function: ForwardMenu
        function ForwardMenuSelected(app, event)
            app.RampDirection = 1;
            app.PlotRamp();
        end

        % Menu selected function: BackwardMenu
        function BackwardMenuSelected(app, event)
            app.RampDirection = 2;
            app.PlotRamp();
        end

        % Menu selected function: BothMenu
        function BothMenuSelected(app, event)
            app.RampDirection = 3;
            app.PlotRamp();
        end

        % Menu selected function: YOffsetAverageMenu
        function YOffsetAverageMenuSelected(app, event)
            % Substracts an Y offset to the raw FZ ramp            
            %
            % Change the Ramp Y Representation to 1 (photodetector signal)
            app.RampYRepresentation = 1;
            app.PlotRamp(); 
            % Call the Parameters App that will return the parameters xmin
            % and xmax defining the range used for determining Y offset
            % ParametersApp can be used to retrieve different sets of
            % parameters. Y offset is called by using the call variable
            % "YOffset"
            ParametersApp(app, "YOffset");
            uiwait(app.UIFigure);
            % Call a ForceVolumeClass method for Y offset substraction
            app.FV.YOffsetAverage(app.Map(4).Value, app.AnalysisParameters);
            % Change the Ramp Y Representation to 2 (offset corrected) before plotting
            % the ramps
            app.RampYRepresentation = 2;
            app.PlotRamp();            
        end

        % Menu selected function: YRepresentationRawMenu
        function YRepresentationRawMenuSelected(app, event)
            app.RampYRepresentation = 1;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationOffsetCorrectedMenu
        function YRepresentationOffsetCorrectedMenuSelected(app, event)
            app.RampYRepresentation = 2;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationDeflectionMenu
        function YRepresentationDeflectionMenuSelected(app, event)
            app.RampYRepresentation = 3;
            app.PlotRamp();
        end

        % Menu selected function: YRepresentationForceMenu
        function YRepresentationForceMenuSelected(app, event)
            app.RampYRepresentation = 4;
            app.PlotRamp();
        end

        % Menu selected function: XRepresentationRawMenu
        function XRepresentationRawMenuSelected(app, event)
            app.RampXRepresentation = 1;
            app.PlotRamp();
        end

        % Menu selected function: XRepresentationOffsetCorrectedMenu
        function XRepresentationOffsetCorrectedMenuSelected(app, event)
            app.RampXRepresentation = 2;
            app.PlotRamp();
        end

        % Menu selected function: 
        % XRepresentationProbeSampleDistanceMenu
        function XRepresentationProbeSampleDistanceMenuSelected(app, event)
            app.RampXRepresentation = 3;
            app.PlotRamp();
        end

        % Menu selected function: RampCalibrationMenu
        function RampCalibrationMenuSelected(app, event)
            % Substracts an Y offset to the raw FZ ramp            
            %
            % Check if all ramps in range had their photodetector values
            % already offset corrected
            checker = 1;
            [row, col, ~] = find(app.Map(4).Value);
            for i = 1:length(row)
                if app.FV.Ramp{row(i),col(i)}.FZRepresentationY(2) == 0
                    checker = 0;
                    break;
                end                
            end
            % If all the specificed ramps had they photodetector signal
            % values offset corrected, the calibration process continues.
            % Otherwise, an error window is called.
            if checker == 1
                % Call the Parameters App that will return the parameters used
                % for ramp calibration
                RampCalibrationParametersApp(app);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
                % Call a ForceVolumeClass method for Y offset substraction
                app.FV.RampCalibration(app.Map(4).Value,...
                    app.AnalysisParameters);
                % Calculates Fadh map
                CalculateMap(app, 8)
                % Change the Ramp Y Representation to 2 (offset corrected)
                app.RampYRepresentation = 4;
                app.PlotRamp();
            else
                % Call the Error Window with specific message
                errorlabel = 'Not all specified ramps had their\nphotodetector signal values offset corrected';
                errorlabel = compose(errorlabel);
                ErrorWindow(app, errorlabel);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
            end
        end

        % Menu selected function: XOffsetHertzModelMenu
        function XOffsetHertzModelMenuSelected(app, event)
            % Finds the contact point according to the Hertz Model,
            % and substracts it for the Sample Vertical Positions.           
            % It also calculates de Young modulus for each ramp, and
            % creates the corresponding map.
            
            % Check if all ramps in range had their photodetector values
            % converted into cantilever deflection values
            checker = 1;
            [row, col, ~] = find(app.Map(4).Value);
            for i = 1:length(row)
                if app.FV.Ramp{row(i), col(i)}.FZRepresentationY(3) == 0
                    checker = 0;
                    break;
                end                
            end
            
            % If all the specificed ramps had their cantilever deflection
            % values calculated, the X Offset process continues.
            % Otherwise, an error window is called.
            if checker == 1
                % The current force ramp is plotted in a offset corrected
                % photodetector values vs. sample displacement
                % representation:
                app.RampYRepresentation = 2;
                app.RampXRepresentation = 1;
                app.PlotRamp();
                
                % Call the external app "ParametersApp" which will let the
                % user define the XF(:,2) range that will be used for Hertz
                % fitting. The minimim and maximum values defining the
                % range will be stored in AnalysisParameters.xForwardMin
                % and AnalysisParameters.xForwardMax.
                ParametersApp(app, "HertzXOffset");
                
                % The app will wait until "ParametersApp" returns values
                % for AnalysisParameters.xForwardMin and 
                % AnalysisParameters.xForwardMax.
                uiwait(app.UIFigure);
                
                % Call a ForceVolumeClass method for Y offset substraction
                app.FV.HertzXOffset(app.Map(4).Value,...
                    app.AnalysisParameters);
                
                % Calculates the Young moduli map
                CalculateMap(app, 6);
                % Calculates the adhesion work map
                CalculateMap(app, 9);
                
                % Change the Ramp Y Representation to 3 (cantilever deflection) and the
                % Ramp X Representation to 2 (offset corrected) before plotting
                % the ramps
                app.RampYRepresentation = 3;
                app.RampXRepresentation = 2;
                app.PlotRamp();
                
                % Plots the Young moduli map
                app.MapRepresentation = 6;
                app.PlotMap()
            else
                % Call the Error Window with specific message
                errorlabel = 'Not all specified ramps had their photodetector signal values\nconverted into cantilever deflection units';
                errorlabel = compose(errorlabel);
                ErrorWindow(app, errorlabel);
                % Wait until information on parameters is retrieved
                uiwait(app.UIFigure);
            end
        end

        % Menu selected function: LinearFitMenu
        function LinearFitMenuSelected(app, event)
            % Call an app used by the user to define the distance range
            % that will be used for the exponential fit
            ParametersApp(app, "LinearFit");
            uiwait(app.UIFigure);
            % Call a ForceVolumeClass method for exponential fit of
            % the selected probe-sample distance range 
            app.FV.LinearFit(app.Map(4).Value, app.AnalysisParameters,...
                app.RampXRepresentation, app.RampYRepresentation);
            % Callacute slope map
            CalculateMap(app, 5);
            % Plot ramps and maps
            app.PlotRamp();
            app.MapRepresentation = 5;
            app.PlotMap();
        end

        % Menu selected function: ExponentialFitMenu
        function ExponentialFitMenuSelected(app, event)
            % Fits with an exponential function the foce vs. probe-sample
            % distance represenation of force ramps (over a range defined
            % by the user.
            
            % First, the current force ramp is plotted in a force vs probe-sample
            % distance representation
            app.RampXRepresentation = 3;
            app.RampYRepresentation = 4;
            app.PlotRamp();
            
            % Call an app used by the user to define the distance range
            % that will be used for the exponential fit
            ParametersApp(app, "ExponentialFit");
            uiwait(app.UIFigure);
            
            % Call an app used by the user to provide estimates of
            % fit parameters
            FitParametersApp(app, "ExponentialFit");
            uiwait(app.UIFigure);
            
            % Call a ForceVolumeClass method for exponential fit of
            % the selected probe-sample distance range 
            app.FV.ExponentialFit(app.Map(4).Value,...
                app.AnalysisParameters);
            % Caluclate exponential amplitude and length maps
            CalculateMap(app, 10);
            CalculateMap(app, 11);
            % Plot ramps and maps
            app.PlotRamp();
            app.MapRepresentation = 10;
            app.PlotMap();
        end

        % Menu selected function: MapViewTopographyMenu
        function MapViewTopographyMenuSelected(app, event)
            app.MapRepresentation = 3;
            app.PlotMap();  
        end

        % Menu selected function: MapViewSelectionMenu
        function MapViewSelectionMenuSelected(app, event)
            app.MapRepresentation = 4;
            app.PlotMap(); 
        end

        % Menu selected function: MapViewSlopeMenu
        function MapViewSlopeMenuSelected(app, event)
            app.MapRepresentation = 6;
            app.PlotMap();
        end

        % Menu selected function: MapViewYoungModulusMenu
        function MapViewYoungModulusMenuSelected(app, event)
            app.MapRepresentation = 6;
            app.PlotMap(); 
        end

        % Menu selected function: MapViewMaximumAdhesionMenu
        function MapViewMaximumAdhesionMenuSelected(app, event)
            app.MapRepresentation = 8;
            app.PlotMap(); 
        end

        % Menu selected function: MapViewAdhesionWorkMenu
        function MapViewAdhesionWorkMenuSelected(app, event)
            app.MapRepresentation = 9;
            app.PlotMap(); 
        end

        % Menu selected function: MapViewExponentialAmplitudeMenu
        function MapViewExponentialAmplitudeMenuSelected(app, event)
            app.MapRepresentation = 10;
            app.PlotMap(); 
        end

        % Menu selected function: MapViewExponentialLengthMenu
        function MapViewExponentialLengthMenuSelected(app, event)
            app.MapRepresentation = 11;
            app.PlotMap(); 
        end

        % Menu selected function: MapHistStandardMenu
        function MapHistStandardMenuSelected(app, event)
            figure;
            histogram(app.Map(app.MapRepresentation).Value(:));
            switch app.MapRepresentation
                case 3
                    xlabel('Height (nm)');
                case 6
                    xlabel('Young Modulus (GPa)');
                case 10
                    xlabel('Exponential Amplitude (nN)');
                case 11
                    xlabel('Exponential Length (nm)');
            end
            ylabel('Counts');
        end

        % Menu selected function: MapHistCustomMenu
        function MapHistCustomMenuSelected(app, event)
            % Call an app used by the user to define the parameters
            % for histogram calculation
            ParametersApp(app, "MapHistogram");
            uiwait(app.UIFigure);
            CustomHistogram(app.Map(app.MapRepresentation).Value,...
                app.MapRepresentation, app.AnalysisParameters.HistMin,...
                app.AnalysisParameters.HistMax, app.AnalysisParameters.HistBins);
        end

        % Menu selected function: ExternalFigureMenu
        function ExternalFigureMenuSelected(app, event)
            PlotRampInExternalFigure(app.FV.Ramp{app.ActualX,app.ActualY},...
                app.RampXRepresentation, app.RampYRepresentation, app.RampDirection);
        end

        % Menu selected function: ExportStructureRampMenu
        function ExportStructureRampMenuSelected(app, event)
            SaveStructure(app.FV.Ramp{app.ActualX,app.ActualY}, 'Save Ramp as:');
        end

        % Menu selected function: ExportStructureMapMenu
        function ExportStructureMapMenuSelected(app, event)
            SaveStructure(app.Map, 'Save Map as:');
        end

        % Menu selected function: ExportStructureForceVolumeMenu
        function ExportStructureForceVolumeMenuSelected(app, event)
            SaveStructure(app.FV, 'Save Force Volume FSAS file as:');
        end

        % Button pushed function: RampZoomInButton
        function RampZoomInButtonPushed(app, event)
            ZoomMinX = app.XMinZoomRampEditField.Value;
            ZoomMaxX = app.XMaxZoomRampEditField.Value;
            ZoomMinY = app.YMinZoomRampEditField.Value;
            ZoomMaxY = app.YMaxZoomRampEditField.Value;
            axis(app.RampAxes, [ZoomMinX ZoomMaxX ZoomMinY ZoomMaxY]);
        end

        % Button pushed function: RampZoomOutButton
        function RampZoomOutButtonPushed(app, event)
            % Zooms out the ramp axes so that all ramp is visualized
            %
            %First, the max and min values in both x and y axis are found
            ZoomMinX = min(min(app.FV.Ramp{app.ActualX,app.ActualY}.XF(:,app.RampXRepresentation)),...
                min(app.FV.Ramp{app.ActualX,app.ActualY}.XB(:,app.RampXRepresentation)));
            ZoomMaxX = max(max(app.FV.Ramp{app.ActualX,app.ActualY}.XF(:,app.RampXRepresentation)),...
                max(app.FV.Ramp{app.ActualX,app.ActualY}.XB(:,app.RampXRepresentation)));
            ZoomMinY = min(min(app.FV.Ramp{app.ActualX,app.ActualY}.YF(:,app.RampYRepresentation)),...
                min(app.FV.Ramp{app.ActualX,app.ActualY}.YB(:,app.RampYRepresentation)));
            ZoomMaxY = max(max(app.FV.Ramp{app.ActualX,app.ActualY}.YF(:,app.RampYRepresentation)),...
                max(app.FV.Ramp{app.ActualX,app.ActualY}.YB(:,app.RampYRepresentation)));
            % Then, the whole range in x and y axis is calculated, and 10%
            % of this value is added to the min and max value of the axis
            % so that all ramp can be visualized
            rangeX = ZoomMaxX - ZoomMinX;
            rangeY = ZoomMaxY - ZoomMinY;
            ZoomMinX = ZoomMinX - rangeX * 0.1;
            ZoomMaxX = ZoomMaxX + rangeX * 0.1;
            ZoomMinY = ZoomMinY - rangeY * 0.1;
            ZoomMaxY = ZoomMaxY + rangeY * 0.1;
            axis(app.RampAxes, [ZoomMinX ZoomMaxX ZoomMinY ZoomMaxY]);
        end

        % Menu selected function: SelectCurrentMenu
        function SelectCurrentMenuSelected(app, event)
            app.FV.Ramp{app.ActualX, app.ActualY}.Property(4) = 1;
            CalculateMap(app, 4);
            app.PlotMap();
        end

        % Menu selected function: SelectAllMenu
        function SelectAllMenuSelected(app, event)
            for i=1:app.FV.NumberOfMapRows
                for j=1:app.FV.NumberOfMapColumns
                    app.FV.Ramp{i, j}.Property(4) = 1;
                end
            end
            CalculateMap(app, 4);
            app.PlotMap();
        end

        % Menu selected function: UnSelectCurrentMenu
        function UnSelectCurrentMenuSelected(app, event)
            app.FV.Ramp{app.ActualX, app.ActualY}.Property(4) = 0;
            CalculateMap(app, 4);
            app.PlotMap();
        end

        % Menu selected function: UnSelectAllMenu
        function UnSelectAllMenuSelected(app, event)
            for i=1:app.FV.NumberOfMapRows
                for j=1:app.FV.NumberOfMapColumns
                    app.FV.Ramp{i, j}.Property(4) = 0;
                end
            end
            CalculateMap(app, 4);
            app.PlotMap();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FSAS Force Volume Analysis';
            app.FZViewMenu = uimenu(app.UIFigure);
            app.FZViewMenu.Text = 'FZ View';
            app.ScanDirectionMenu = uimenu(app.FZViewMenu);
            app.ScanDirectionMenu.Text = 'Scan Direction';
            app.ForwardMenu = uimenu(app.ScanDirectionMenu);
            app.ForwardMenu.MenuSelectedFcn = createCallbackFcn(app, @ForwardMenuSelected, true);
            app.ForwardMenu.Text = 'Forward';
            app.BackwardMenu = uimenu(app.ScanDirectionMenu);
            app.BackwardMenu.MenuSelectedFcn = createCallbackFcn(app, @BackwardMenuSelected, true);
            app.BackwardMenu.Text = 'Backward';
            app.BothMenu = uimenu(app.ScanDirectionMenu);
            app.BothMenu.MenuSelectedFcn = createCallbackFcn(app, @BothMenuSelected, true);
            app.BothMenu.Text = 'Both';
            app.XRepresentationMenu = uimenu(app.FZViewMenu);
            app.XRepresentationMenu.Text = 'X Representation';
            app.XRepresentationRawMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationRawMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationRawMenuSelected, true);
            app.XRepresentationRawMenu.Text = 'Raw';
            app.XRepresentationOffsetCorrectedMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationOffsetCorrectedMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationOffsetCorrectedMenuSelected, true);
            app.XRepresentationOffsetCorrectedMenu.Text = 'Offset Corrected';
            app.XRepresentationProbeSampleDistanceMenu = uimenu(app.XRepresentationMenu);
            app.XRepresentationProbeSampleDistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @XRepresentationProbeSampleDistanceMenuSelected, true);
            app.XRepresentationProbeSampleDistanceMenu.Text = 'Probe-Sample Distance';
            app.YRepresentationMenu = uimenu(app.FZViewMenu);
            app.YRepresentationMenu.Text = 'Y Representation';
            app.YRepresentationRawMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationRawMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationRawMenuSelected, true);
            app.YRepresentationRawMenu.Text = 'Raw';
            app.YRepresentationOffsetCorrectedMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationOffsetCorrectedMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationOffsetCorrectedMenuSelected, true);
            app.YRepresentationOffsetCorrectedMenu.Text = 'Offset Corrected';
            app.YRepresentationDeflectionMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationDeflectionMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationDeflectionMenuSelected, true);
            app.YRepresentationDeflectionMenu.Text = 'Deflection';
            app.YRepresentationForceMenu = uimenu(app.YRepresentationMenu);
            app.YRepresentationForceMenu.MenuSelectedFcn = createCallbackFcn(app, @YRepresentationForceMenuSelected, true);
            app.YRepresentationForceMenu.Text = 'Force';
            app.ExternalFigureMenu = uimenu(app.FZViewMenu);
            app.ExternalFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ExternalFigureMenuSelected, true);
            app.ExternalFigureMenu.Text = 'External Figure';
            app.FZAnalysisMenu = uimenu(app.UIFigure);
            app.FZAnalysisMenu.Text = 'FZ Analysis';
            app.YOffsetMenu = uimenu(app.FZAnalysisMenu);
            app.YOffsetMenu.Text = 'Y Offset';
            app.YOffsetAverageMenu = uimenu(app.YOffsetMenu);
            app.YOffsetAverageMenu.MenuSelectedFcn = createCallbackFcn(app, @YOffsetAverageMenuSelected, true);
            app.YOffsetAverageMenu.Text = 'Average';
            app.RampCalibrationMenu = uimenu(app.FZAnalysisMenu);
            app.RampCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @RampCalibrationMenuSelected, true);
            app.RampCalibrationMenu.Text = 'Ramp Calibration';
            app.XOffsetMenu = uimenu(app.FZAnalysisMenu);
            app.XOffsetMenu.Text = 'X Offset';
            app.XOffsetHertzModelMenu = uimenu(app.XOffsetMenu);
            app.XOffsetHertzModelMenu.MenuSelectedFcn = createCallbackFcn(app, @XOffsetHertzModelMenuSelected, true);
            app.XOffsetHertzModelMenu.Text = 'Hertz Model';
            app.LinearFitMenu = uimenu(app.FZAnalysisMenu);
            app.LinearFitMenu.MenuSelectedFcn = createCallbackFcn(app, @LinearFitMenuSelected, true);
            app.LinearFitMenu.Text = 'Linear Fit';
            app.ExponentialFitMenu = uimenu(app.FZAnalysisMenu);
            app.ExponentialFitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExponentialFitMenuSelected, true);
            app.ExponentialFitMenu.Text = 'Exponential Fit';
            app.MapViewMenu = uimenu(app.UIFigure);
            app.MapViewMenu.Text = 'Map View';
            app.MapViewTopographyMenu = uimenu(app.MapViewMenu);
            app.MapViewTopographyMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewTopographyMenuSelected, true);
            app.MapViewTopographyMenu.Text = 'Topography';
            app.MapViewSelectionMenu = uimenu(app.MapViewMenu);
            app.MapViewSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewSelectionMenuSelected, true);
            app.MapViewSelectionMenu.Text = 'Selection';
            app.MapViewSlopeMenu = uimenu(app.MapViewMenu);
            app.MapViewSlopeMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewSlopeMenuSelected, true);
            app.MapViewSlopeMenu.Text = 'Slope';
            app.MapViewYoungModulusMenu = uimenu(app.MapViewMenu);
            app.MapViewYoungModulusMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewYoungModulusMenuSelected, true);
            app.MapViewYoungModulusMenu.Text = 'Young Modulus';
            app.MapViewMaximumAdhesionMenu = uimenu(app.MapViewMenu);
            app.MapViewMaximumAdhesionMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewMaximumAdhesionMenuSelected, true);
            app.MapViewMaximumAdhesionMenu.Text = 'Maximum Adhesion';
            app.MapViewAdhesionWorkMenu = uimenu(app.MapViewMenu);
            app.MapViewAdhesionWorkMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewAdhesionWorkMenuSelected, true);
            app.MapViewAdhesionWorkMenu.Text = 'Adhesion Work';
            app.MapViewExponentialAmplitudeMenu = uimenu(app.MapViewMenu);
            app.MapViewExponentialAmplitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewExponentialAmplitudeMenuSelected, true);
            app.MapViewExponentialAmplitudeMenu.Text = 'Exponential Amplitude';
            app.MapViewExponentialLengthMenu = uimenu(app.MapViewMenu);
            app.MapViewExponentialLengthMenu.MenuSelectedFcn = createCallbackFcn(app, @MapViewExponentialLengthMenuSelected, true);
            app.MapViewExponentialLengthMenu.Text = 'Exponential Length';
            app.MapAnalysisMenu = uimenu(app.UIFigure);
            app.MapAnalysisMenu.Text = 'Map Analysis';
            app.SelectionMenu = uimenu(app.MapAnalysisMenu);
            app.SelectionMenu.Text = 'Selection';
            app.SelectMenu = uimenu(app.SelectionMenu);
            app.SelectMenu.Text = 'Select';
            app.SelectCurrentMenu = uimenu(app.SelectMenu);
            app.SelectCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectCurrentMenuSelected, true);
            app.SelectCurrentMenu.Text = 'Current';
            app.SelectAllMenu = uimenu(app.SelectMenu);
            app.SelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllMenuSelected, true);
            app.SelectAllMenu.Text = 'All';
            app.UnSelectMenu = uimenu(app.SelectionMenu);
            app.UnSelectMenu.Text = 'UnSelect';
            app.UnSelectCurrentMenu = uimenu(app.UnSelectMenu);
            app.UnSelectCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @UnSelectCurrentMenuSelected, true);
            app.UnSelectCurrentMenu.Text = 'Current';
            app.UnSelectAllMenu = uimenu(app.UnSelectMenu);
            app.UnSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @UnSelectAllMenuSelected, true);
            app.UnSelectAllMenu.Text = 'All';
            app.HistogramMenu = uimenu(app.MapAnalysisMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.MapHistStandardMenu = uimenu(app.HistogramMenu);
            app.MapHistStandardMenu.MenuSelectedFcn = createCallbackFcn(app, @MapHistStandardMenuSelected, true);
            app.MapHistStandardMenu.Text = 'Standard';
            app.MapHistCustomMenu = uimenu(app.HistogramMenu);
            app.MapHistCustomMenu.MenuSelectedFcn = createCallbackFcn(app, @MapHistCustomMenuSelected, true);
            app.MapHistCustomMenu.Text = 'Custom';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.MatlabStructureMenu = uimenu(app.ExportMenu);
            app.MatlabStructureMenu.Text = 'Matlab Structure';
            app.ExportStructureRampMenu = uimenu(app.MatlabStructureMenu);
            app.ExportStructureRampMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStructureRampMenuSelected, true);
            app.ExportStructureRampMenu.Text = 'Ramp';
            app.ExportStructureMapMenu = uimenu(app.MatlabStructureMenu);
            app.ExportStructureMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStructureMapMenuSelected, true);
            app.ExportStructureMapMenu.Text = 'Map';
            app.ExportStructureForceVolumeMenu = uimenu(app.MatlabStructureMenu);
            app.ExportStructureForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStructureForceVolumeMenuSelected, true);
            app.ExportStructureForceVolumeMenu.Text = 'Force Volume';
            app.RampAxes = uiaxes(app.UIFigure);
            title(app.RampAxes, 'Title')
            xlabel(app.RampAxes, 'X')
            ylabel(app.RampAxes, 'Y')
            app.MapAxes = uiaxes(app.UIFigure);
            title(app.MapAxes, 'Title')
            xlabel(app.MapAxes, 'X')
            ylabel(app.MapAxes, 'Y')
            app.ZoomRampPanel = uipanel(app.UIFigure);
            app.ZoomRampPanel.Title = 'Zoom Ramp';
            app.XMinEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.XMinEditFieldLabel.Text = 'X Min';
            app.XMinZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.XMaxEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.XMaxEditFieldLabel.Text = 'X Max';
            app.XMaxZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.YMinEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.YMinEditFieldLabel.Text = 'Y Min';
            app.YMinZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.YMaxEditFieldLabel = uilabel(app.ZoomRampPanel);
            app.YMaxEditFieldLabel.Text = 'Y Max';
            app.YMaxZoomRampEditField = uieditfield(app.ZoomRampPanel, 'numeric');
            app.RampZoomInButton = uibutton(app.ZoomRampPanel, 'push');
            app.RampZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @RampZoomInButtonPushed, true);
            app.RampZoomInButton.Text = {'Zoom'; 'In'};
            app.RampZoomOutButton = uibutton(app.ZoomRampPanel, 'push');
            app.RampZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @RampZoomOutButtonPushed, true);
            app.RampZoomOutButton.Text = {'Zoom'; 'Out'};
            app.MapNavigationPanel = uipanel(app.UIFigure);
            app.MapNavigationPanel.Title = 'Map Navigation';
            app.UpButton = uibutton(app.MapNavigationPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.MapNavigationPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.MapNavigationPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.MapNavigationPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.CurrentRampTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentRampTextAreaLabel.Text = {'Current'; 'Ramp'};
            app.CurrentRampTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceVolumeAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end