classdef ParametersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MainLabel        matlab.ui.control.Label
        EditFieldLabel   matlab.ui.control.Label
        EditField1       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.NumericEditField
        OKButton         matlab.ui.control.Button
        EditField3Label  matlab.ui.control.Label
        EditField3       matlab.ui.control.NumericEditField
        EditField4Label  matlab.ui.control.Label
        EditField4       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Calling Application
        Option % Specifies the purpose of the application
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, option)
            app.CallingApp = mainapp;
            app.Option=option;
            switch option
                case "YOffset"
                    app.EditFieldLabel.Text = "X Forward Min";
                    app.EditField2Label.Text = "X Forward Max";
                    app.EditField3Label.Text = "X Backward Min";
                    app.EditField4Label.Text = "X Backward Max";                    
                    app.MainLabel.Text = "Y Offset Parameters";
                case "HertzXOffset"
                    app.EditFieldLabel.Text = "X Forward Min";
                    app.EditField2Label.Text = "X Forward Max";
                    app.EditField3Label.Visible = "Off";
                    app.EditField4Label.Visible = "Off";
                    app.EditField3.Visible = "Off";
                    app.EditField4.Visible = "Off"; 
                    app.MainLabel.Text = "Vertical position range for Hertz fitting";
                case "LinearFit"
                    app.EditFieldLabel.Text = "X Forward Min";
                    app.EditField2Label.Text = "X Forward Max";
                    app.EditField3Label.Visible = "Off";
                    app.EditField4Label.Visible = "Off";
                    app.EditField3.Visible = "Off";
                    app.EditField4.Visible = "Off"; 
                    app.MainLabel.Text = "X-range for linear fitting";
                case "ExponentialFit"
                    app.EditFieldLabel.Text = "X Forward Min";
                    app.EditField2Label.Text = "X Forward Max";
                    app.EditField3Label.Visible = "Off";
                    app.EditField4Label.Visible = "Off";
                    app.EditField3.Visible = "Off";
                    app.EditField4.Visible = "Off"; 
                    app.MainLabel.Text = "Probe-sample distance range for exponetial fitting";
                case "MapHistogram"
                    app.EditFieldLabel.Text = "Min Value";
                    app.EditField2Label.Text = "No Bins";
                    app.EditField3Label.Text = "Max Value";
                    app.EditField4Label.Visible = "Off";
                    app.EditField4.Visible = "Off"; 
                    app.MainLabel.Text = "Parameters for Histogram Calculation";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            switch app.Option
                case "YOffset"
                    app.CallingApp.AnalysisParameters.xForwardMin = app.EditField1.Value;
                    app.CallingApp.AnalysisParameters.xForwardMax = app.EditField2.Value;
                    app.CallingApp.AnalysisParameters.xBackwardMin = app.EditField3.Value;
                    app.CallingApp.AnalysisParameters.xBackwardMax = app.EditField4.Value;
                case "HertzXOffset"
                    app.CallingApp.AnalysisParameters.xForwardMin = app.EditField1.Value;
                    app.CallingApp.AnalysisParameters.xForwardMax = app.EditField2.Value;
                case "LinearFit"
                    app.CallingApp.AnalysisParameters.xForwardMin = app.EditField1.Value;
                    app.CallingApp.AnalysisParameters.xForwardMax = app.EditField2.Value;
                case "ExponentialFit"
                    app.CallingApp.AnalysisParameters.xForwardMin = app.EditField1.Value;
                    app.CallingApp.AnalysisParameters.xForwardMax = app.EditField2.Value;
                case "MapHistogram"
                    app.CallingApp.AnalysisParameters.HistMin = app.EditField1.Value;
                    app.CallingApp.AnalysisParameters.HistMax = app.EditField3.Value;
                    app.CallingApp.AnalysisParameters.HistBins = app.EditField2.Value;
            end                    
            uiresume(app.CallingApp.UIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainLabel = uilabel(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField1.Value = 70;
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Value = 100;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Value = 200;
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Value = 250;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParametersApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end