classdef RampCalibrationParametersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RampCalibrationParametersUIFigure  matlab.ui.Figure
        PhotodetectorSensitivitynmVEditFieldLabel  matlab.ui.control.Label
        PhotodetectorSensitivityEditField  matlab.ui.control.NumericEditField
        SpringConstantnNnmEditFieldLabel  matlab.ui.control.Label
        SpringConstantEditField      matlab.ui.control.NumericEditField
        ProbeRadiusnmEditFieldLabel  matlab.ui.control.Label
        ProbeRadiusEditField         matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel   matlab.ui.control.Label
        PoissonRatioEditField        matlab.ui.control.NumericEditField
        OKButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.AnalysisParameters.PhotodetectorSensitivity = app.PhotodetectorSensitivityEditField.Value;
            app.CallingApp.AnalysisParameters.SpringConstant = app.SpringConstantEditField.Value;
            app.CallingApp.AnalysisParameters.ProbeRadius = app.ProbeRadiusEditField.Value;
            app.CallingApp.AnalysisParameters.PoissonRatio = app.PoissonRatioEditField.Value;
            uiresume(app.CallingApp.UIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RampCalibrationParametersUIFigure = uifigure('Visible', 'off');
            app.RampCalibrationParametersUIFigure.Name = 'Ramp Calibration Parameters';
            app.PhotodetectorSensitivitynmVEditFieldLabel = uilabel(app.RampCalibrationParametersUIFigure);
            app.PhotodetectorSensitivitynmVEditFieldLabel.Text = 'Photodetector Sensitivity (nm/V)';
            app.PhotodetectorSensitivityEditField = uieditfield(app.RampCalibrationParametersUIFigure, 'numeric');
            app.PhotodetectorSensitivityEditField.Value = 41.72;
            app.SpringConstantnNnmEditFieldLabel = uilabel(app.RampCalibrationParametersUIFigure);
            app.SpringConstantnNnmEditFieldLabel.Text = 'Spring Constant (nN/nm)';
            app.SpringConstantEditField = uieditfield(app.RampCalibrationParametersUIFigure, 'numeric');
            app.SpringConstantEditField.Value = 0.0387;
            app.ProbeRadiusnmEditFieldLabel = uilabel(app.RampCalibrationParametersUIFigure);
            app.ProbeRadiusnmEditFieldLabel.Text = 'Probe Radius (nm)';
            app.ProbeRadiusEditField = uieditfield(app.RampCalibrationParametersUIFigure, 'numeric');
            app.ProbeRadiusEditField.Value = 30;
            app.PoissonRatioEditFieldLabel = uilabel(app.RampCalibrationParametersUIFigure);
            app.PoissonRatioEditFieldLabel.Text = 'Poisson Ratio';
            app.PoissonRatioEditField = uieditfield(app.RampCalibrationParametersUIFigure, 'numeric');
            app.PoissonRatioEditField.Value = 0.3;
            app.OKButton = uibutton(app.RampCalibrationParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.RampCalibrationParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RampCalibrationParametersApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RampCalibrationParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RampCalibrationParametersUIFigure)
        end
    end
end